classdef DieselCycleSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DieselCycleParametersTab        matlab.ui.container.Tab
        InitialtempEditFieldLabel       matlab.ui.control.Label
        InitialtempEditField            matlab.ui.control.NumericEditField
        InitialpressEditFieldLabel      matlab.ui.control.Label
        InitialpressEditField           matlab.ui.control.NumericEditField
        SpecificheatcapacityofvEditFieldLabel  matlab.ui.control.Label
        SpecificheatcapacityofvEditField  matlab.ui.control.NumericEditField
        SpecificheatcapacityofpEditFieldLabel  matlab.ui.control.Label
        SpecificheatcapacityofpEditField  matlab.ui.control.NumericEditField
        GasconstantEditFieldLabel       matlab.ui.control.Label
        GasconstantEditField            matlab.ui.control.NumericEditField
        CompressionratioEditFieldLabel  matlab.ui.control.Label
        CompressionratioEditField       matlab.ui.control.NumericEditField
        CutoffratioEditFieldLabel       matlab.ui.control.Label
        CutoffratioEditField            matlab.ui.control.NumericEditField
        RunSimulationButton             matlab.ui.control.Button
        CylinderBoreDiameterEditFieldLabel  matlab.ui.control.Label
        CylinderBoreDiameterEditField   matlab.ui.control.NumericEditField
        StrokeLengthEditFieldLabel      matlab.ui.control.Label
        StrokeLengthEditField           matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Initial_temp, Initial_press, Gas_constant, Stroke_Length_Compression_Ratio_Cut_off_ratio, Specific_heat_capacity_of_p, Specific_heat_capacity_of_v, Cylinder_Bore_Diameter)
            
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
   %Extract cycle parameters
           cycle_parameters = inputdlg('Initial temp','Initial press','Gas constant','Compression ratio','Cut-off ratio','Specific heat capacity of p','Specific heat capacity of v','Stroke Length','Cylinder Bore Diameter');
          
           %Check for empty paramters and output an error message if any
           for i=1:9
               if isempty(cycle_parameters(i))
                   Err = msgbox('Inital cycle parameter left blank. Retry');
                   return
               end
           end
          
           %Convert character array to numeric matrix
           Ti = str2num(cycle_parameters(1));
           Pi = str2num(cycle_parameters(2));
           Cp = str2num(cycle_parameters(3));
           Cv = str2num(cycle_parameters(4));
           R = str2num(cycle_parameters(5));
           cr = str2num(cycle_parameters(6));
           cor = str2num(cycle_parameters(7));
          L_s = str2num(cycle_parameters(8));
           D_c = str2num(cycle_parameters(9));
          
            %Check temperature value
          
           if (Ti == 0)
               Terr = msgbox('Inital temperature value can not be zero. Retry');
               return
           end
          
             %Check pressure value
            
           if (Pi == 0)
               Perr = msgbox ('Inital pressure value can not be zero. Retry');
               return
           end
           
           %Check cut-off ratio value
          
           if (cor == 0)
               CORerr = msgbox('Cut-off ratio value can not be zero. Retry');
               return
           end
          
            %Check compression ratio value
            
            if (cr < 12)
                 Crerr = msgbox ('Compression ration should be between 12 and 24. Retry');
                 return
               else if (cr > 24)
                     CRerr= msgbox ('Compression ration should be between 12 and 24. Retry');
                     return
               end
            end
            
             %input other variables
             gamma = Cp/Cv;
            
             %Calculate swept and clearance volumes
            
             Vs = (pi/4)*(D_c^2)*(L_s);
             Vc = Vs / (cr-1);
            
             %Calculate variables at different states
             % State 1
            
             vD1 = Vs + Vc;
             pD1 = Pi;
             tD1 = Ti;
            
             % State 2
            
             vD2 = Vc;
             pD2 = pD1 * (cr^gamma);
             tD2 = tD1 * (cr^(gamma-1));
            
             % State 3
            
             vD3 = cor * vD2;
             pD3 = pD2;
             tD3 = cor * tD2;
            
             % State 4
            
             vD4 = vD1;
             pD4 = pD3 * ((vD3/vD4)^gamma);
             tD4 = (pD4*vD4*tD3) / (pD3*vD3);
            
             %Compression Curve from State 1 to State 2
            
             Comp_vD = linspace (vD1,vD2,100);
             Comp_pD = (((vD1./Comp_vD).^gamma)*pD1);
            
             %Expansion Curve from State 3 to State 4
            
             Exp_vD = linspace (vD3,vD4,100);
             Exp_pD = (((vD3./Exp_vD).^gamma)*pD3);
            
             % Calculate Output parameters
            
             %Thermal_Efficiency
             Thermal_Efficiency = 1-((1/(cr^(gamma-1)))*(((cor^gamma)-1)/(gamma*(cor-1))));
            
             %Net work output
             mass = pD1*vD1*180/(R+tD1);
             Qa = mass*Cp*(tD3-tD2)*1000;
             Wnet = Qa*Thermal_Efficiency/1000;
           
             %Mean Effective Pressure
             MEP = Wnet/(vD1-vD2);
            
             %Display output parameters
             Output = msgbox ({['Mean Effective Pressure = ', num2str(MEP)], ['Thermal Efficiency=',num2str(Thermal_Efficiency)], ['Net Work Done=',num2str(Wnet)]});
             disp('View P_V Diagram')
             uiwait(Output)
             clc
             cd('ArData/')
             
             %Plot P-V Curve
             m = 1;
             figure (m)
             hold on
             grid on
             title ('Ideal Diesel Cycle')
             hold on
             plot (Comp_vD, Comp_pD,'color','r')
             hold on
             plot ([vD2 vD3],[pD2 pD3],'color','m')
             hold on
             plot (Exp_vD, Exp_pD, 'color', 'r')
             hold on
             plot ([vD4 vD1],[pD4 pD1],'color','m')
             xlabel ('Volume')
             ylabel ('Pressure')
          
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DieselCycleParametersTab = uitab(app.TabGroup);
            app.DieselCycleParametersTab.Title = 'Diesel Cycle Parameters';
            app.InitialtempEditFieldLabel = uilabel(app.DieselCycleParametersTab);
            app.InitialtempEditFieldLabel.Text = 'Initial temp';
            app.InitialtempEditField = uieditfield(app.DieselCycleParametersTab, 'numeric');
            app.InitialpressEditFieldLabel = uilabel(app.DieselCycleParametersTab);
            app.InitialpressEditFieldLabel.Text = 'Initial press';
            app.InitialpressEditField = uieditfield(app.DieselCycleParametersTab, 'numeric');
            app.SpecificheatcapacityofvEditFieldLabel = uilabel(app.DieselCycleParametersTab);
            app.SpecificheatcapacityofvEditFieldLabel.Text = 'Specific heat capacity of v';
            app.SpecificheatcapacityofvEditField = uieditfield(app.DieselCycleParametersTab, 'numeric');
            app.SpecificheatcapacityofpEditFieldLabel = uilabel(app.DieselCycleParametersTab);
            app.SpecificheatcapacityofpEditFieldLabel.Text = 'Specific heat capacity of p';
            app.SpecificheatcapacityofpEditField = uieditfield(app.DieselCycleParametersTab, 'numeric');
            app.GasconstantEditFieldLabel = uilabel(app.DieselCycleParametersTab);
            app.GasconstantEditFieldLabel.Text = 'Gas constant';
            app.GasconstantEditField = uieditfield(app.DieselCycleParametersTab, 'numeric');
            app.CompressionratioEditFieldLabel = uilabel(app.DieselCycleParametersTab);
            app.CompressionratioEditFieldLabel.Text = 'Compression ratio';
            app.CompressionratioEditField = uieditfield(app.DieselCycleParametersTab, 'numeric');
            app.CutoffratioEditFieldLabel = uilabel(app.DieselCycleParametersTab);
            app.CutoffratioEditFieldLabel.Text = 'Cut-off ratio';
            app.CutoffratioEditField = uieditfield(app.DieselCycleParametersTab, 'numeric');
            app.RunSimulationButton = uibutton(app.DieselCycleParametersTab, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.CylinderBoreDiameterEditFieldLabel = uilabel(app.DieselCycleParametersTab);
            app.CylinderBoreDiameterEditFieldLabel.Text = 'Cylinder Bore Diameter';
            app.CylinderBoreDiameterEditField = uieditfield(app.DieselCycleParametersTab, 'numeric');
            app.StrokeLengthEditFieldLabel = uilabel(app.DieselCycleParametersTab);
            app.StrokeLengthEditFieldLabel.Text = 'Stroke Length';
            app.StrokeLengthEditField = uieditfield(app.DieselCycleParametersTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DieselCycleSimulation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end